classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        LoadButton       matlab.ui.control.Button
        PlayButton       matlab.ui.control.Button
        PauseButton      matlab.ui.control.Button
        StopButton       matlab.ui.control.Button
        HzSliderLabel    matlab.ui.control.Label
        gain1            matlab.ui.control.Slider
        HzSlider_2Label  matlab.ui.control.Label
        gain2            matlab.ui.control.Slider
        HzSlider_6Label  matlab.ui.control.Label
        gain7            matlab.ui.control.Slider
        HzSlider_5Label  matlab.ui.control.Label
        gain6            matlab.ui.control.Slider
        HZSliderLabel    matlab.ui.control.Label
        gain5            matlab.ui.control.Slider
        HzSlider_4Label  matlab.ui.control.Label
        gain4            matlab.ui.control.Slider
        HzSlider_3Label  matlab.ui.control.Label
        gain3            matlab.ui.control.Slider
        HzSlider_7Label  matlab.ui.control.Label
        gain8            matlab.ui.control.Slider
        HzSlider_8Label  matlab.ui.control.Label
        gain9            matlab.ui.control.Slider
        HzSlider_9Label  matlab.ui.control.Label
        gain10           matlab.ui.control.Slider
        Label            matlab.ui.control.Label
        UIAxes           matlab.ui.control.UIAxes
        UIAxes2          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
         equalizer=Equalizer(64,44100) %Description
         deviceWriter=audioDeviceReader('SampleRate',44100)
         fileReader=dsp.AudioFileReader
         
    end
    properties(Access = protected)
        filename %Description
        stopFlag=false
    end
    methods(Access=private)
        function PlotFreqResponse(app)
            [Hdb,w] = app.equalizer.GetFreqResponse();
            for i=1:10
                plot(app.UIAxes2,w(i,:),Hdb(i,:))
            end
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.PlotFreqResponse();
        end

        % Value changed function: gain1
        function gain1ValueChanged(app, event)
            app.equalizer.gain(1) = app.gain1.Value;
            app.PlotFreqResponse();
            drawnow
        end

        % Value changed function: gain2
        function gain2ValueChanged(app, event)
            app.equalizer.gain(2) = app.gain2.Value;
            app.PlotFreqResponse();
            drawnow
        end

        % Value changed function: gain3
        function gain3ValueChanged(app, event)
            app.equalizer.gain(3) = app.gain3.Value;
            app.PlotFreqResponse();
            drawnow
        end

        % Value changed function: gain4
        function gain4ValueChanged(app, event)
            app.equalizer.gain(4) = app.gain4.Value;
            app.PlotFreqResponse();
            drawnow
        end

        % Value changed function: gain5
        function gain5ValueChanged(app, event)
            app.equalizer.gain(5) = app.gain5.Value;
            app.PlotFreqResponse();
            drawnow
        end

        % Value changed function: gain6
        function gain6ValueChanged(app, event)
            app.equalizer.gain(6) = app.gain6.Value;
            app.PlotFreqResponse();
            drawnow
        end

        % Value changed function: gain7
        function gain7ValueChanged(app, event)
           app.equalizer.gain(7) = app.gain7.Value;
            app.PlotFreqResponse();
            drawnow
        end

        % Value changed function: gain8
        function gain8ValueChanged(app, event)
            app.equalizer.gain(8) = app.gain8.Value;
            app.PlotFreqResponse();
            drawnow
        end

        % Value changed function: gain9
        function gain9ValueChanged(app, event)
            app.equalizer.gain(9) = app.gain9.Value;
            app.PlotFreqResponse();
            drawnow
        end

        % Value changed function: gain10
        function gain10ValueChanged(app, event)
            app.equalizer.gain(10) = app.gain10.Value;
            app.PlotFreqResponse();
            drawnow
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
           app.filename=uigetfile('*.mp3');
           app.Label.Text=app.filename;
           app.fileReader=dsp.AudioFileReader(app.filename,'PlayCount',inf,'SamplesPerFrame',4096);
           drawnow
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
          app.stopFlag=true;
          drawnow
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
         app.stopFlag=true;
         drawnow
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.HzSliderLabel = uilabel(app.UIFigure);
            app.HzSliderLabel.Text = '31Hz';
            app.gain1 = uislider(app.UIFigure);
            app.gain1.Limits = [-10 10];
            app.gain1.MajorTicks = [-10 -8 -6 -4 -2 0 2 4 6 8 10];
            app.gain1.MajorTickLabels = {'-10', '-8', '-6', '-4', '-2', '0', '2', '4', '6', '8', '10'};
            app.gain1.ValueChangedFcn = createCallbackFcn(app, @gain1ValueChanged, true);
            app.gain1.MinorTicks = [-10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10];
            app.HzSlider_2Label = uilabel(app.UIFigure);
            app.HzSlider_2Label.Text = '62Hz';
            app.gain2 = uislider(app.UIFigure);
            app.gain2.Limits = [-10 10];
            app.gain2.MajorTicks = [-10 -8 -6 -4 -2 0 2 4 6 8 10];
            app.gain2.MajorTickLabels = {'-10', '-8', '-6', '-4', '-2', '0', '2', '4', '6', '8', '10'};
            app.gain2.ValueChangedFcn = createCallbackFcn(app, @gain2ValueChanged, true);
            app.gain2.MinorTicks = [-10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10];
            app.HzSlider_6Label = uilabel(app.UIFigure);
            app.HzSlider_6Label.Text = '2000Hz';
            app.gain7 = uislider(app.UIFigure);
            app.gain7.Limits = [-10 10];
            app.gain7.MajorTicks = [-10 -8 -6 -4 -2 0 2 4 6 8 10];
            app.gain7.MajorTickLabels = {'-10', '-8', '-6', '-4', '-2', '0', '2', '4', '6', '8', '10'};
            app.gain7.ValueChangedFcn = createCallbackFcn(app, @gain7ValueChanged, true);
            app.gain7.MinorTicks = [-10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10];
            app.HzSlider_5Label = uilabel(app.UIFigure);
            app.HzSlider_5Label.Text = '1000Hz';
            app.gain6 = uislider(app.UIFigure);
            app.gain6.Limits = [-10 10];
            app.gain6.MajorTicks = [-10 -8 -6 -4 -2 0 2 4 6 8 10];
            app.gain6.MajorTickLabels = {'-10', '-8', '-6', '-4', '-2', '0', '2', '4', '6', '8', '10'};
            app.gain6.ValueChangedFcn = createCallbackFcn(app, @gain6ValueChanged, true);
            app.gain6.MinorTicks = [-10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10];
            app.HZSliderLabel = uilabel(app.UIFigure);
            app.HZSliderLabel.Text = '500HZ';
            app.gain5 = uislider(app.UIFigure);
            app.gain5.Limits = [-10 10];
            app.gain5.MajorTicks = [-10 -8 -6 -4 -2 0 2 4 6 8 10];
            app.gain5.MajorTickLabels = {'-10', '-8', '-6', '-4', '-2', '0', '2', '4', '6', '8', '10'};
            app.gain5.ValueChangedFcn = createCallbackFcn(app, @gain5ValueChanged, true);
            app.gain5.MinorTicks = [-10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10];
            app.HzSlider_4Label = uilabel(app.UIFigure);
            app.HzSlider_4Label.Text = '250Hz';
            app.gain4 = uislider(app.UIFigure);
            app.gain4.Limits = [-10 10];
            app.gain4.MajorTicks = [-10 -8 -6 -4 -2 0 2 4 6 8 10];
            app.gain4.MajorTickLabels = {'-10', '-8', '-6', '-4', '-2', '0', '2', '4', '6', '8', '10'};
            app.gain4.ValueChangedFcn = createCallbackFcn(app, @gain4ValueChanged, true);
            app.gain4.MinorTicks = [-10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10];
            app.HzSlider_3Label = uilabel(app.UIFigure);
            app.HzSlider_3Label.Text = '125Hz';
            app.gain3 = uislider(app.UIFigure);
            app.gain3.Limits = [-10 10];
            app.gain3.MajorTicks = [-10 -8 -6 -4 -2 0 2 4 6 8 10];
            app.gain3.MajorTickLabels = {'-10', '-8', '-6', '-4', '-2', '0', '2', '4', '6', '8', '10'};
            app.gain3.ValueChangedFcn = createCallbackFcn(app, @gain3ValueChanged, true);
            app.gain3.MinorTicks = [-10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10];
            app.HzSlider_7Label = uilabel(app.UIFigure);
            app.HzSlider_7Label.Text = '4000Hz';
            app.gain8 = uislider(app.UIFigure);
            app.gain8.Limits = [-10 10];
            app.gain8.MajorTicks = [-10 -8 -6 -4 -2 0 2 4 6 8 10];
            app.gain8.MajorTickLabels = {'-10', '-8', '-6', '-4', '-2', '0', '2', '4', '6', '8', '10'};
            app.gain8.ValueChangedFcn = createCallbackFcn(app, @gain8ValueChanged, true);
            app.gain8.MinorTicks = [-10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10];
            app.HzSlider_8Label = uilabel(app.UIFigure);
            app.HzSlider_8Label.Text = '8000Hz';
            app.gain9 = uislider(app.UIFigure);
            app.gain9.Limits = [-10 10];
            app.gain9.MajorTicks = [-10 -8 -6 -4 -2 0 2 4 6 8 10];
            app.gain9.MajorTickLabels = {'-10', '-8', '-6', '-4', '-2', '0', '2', '4', '6', '8', '10'};
            app.gain9.ValueChangedFcn = createCallbackFcn(app, @gain9ValueChanged, true);
            app.gain9.MinorTicks = [-10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10];
            app.HzSlider_9Label = uilabel(app.UIFigure);
            app.HzSlider_9Label.Text = '16000Hz';
            app.gain10 = uislider(app.UIFigure);
            app.gain10.Limits = [-10 10];
            app.gain10.MajorTicks = [-10 -8 -6 -4 -2 0 2 4 6 8 10];
            app.gain10.MajorTickLabels = {'-10', '-8', '-6', '-4', '-2', '0', '2', '4', '6', '8', '10'};
            app.gain10.ValueChangedFcn = createCallbackFcn(app, @gain10ValueChanged, true);
            app.gain10.MinorTicks = [-10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10];
            app.Label = uilabel(app.UIFigure);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, {'Spectrum'; ''})
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'FreqResponce')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end